classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CutoffFrequency2             matlab.ui.control.NumericEditField
        CF2Label                     matlab.ui.control.Label
        CutoffFrequency1             matlab.ui.control.NumericEditField
        CF1Label                     matlab.ui.control.Label
        VolumeSlider                 matlab.ui.control.Slider
        VolumeSliderLabel            matlab.ui.control.Label
        PlaywithFilterButton         matlab.ui.control.Button
        PlaySoundButton              matlab.ui.control.Button
        NameSound                    matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        FilterDropDown               matlab.ui.control.DropDown
        FilterDropDownLabel          matlab.ui.control.Label
        AudioFilterApplicationLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Fs          double
        AudioFile   double
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
             % Load the audio data from a file
            [y, fs] = audioread(app.NameSound.Text);
            
            sound(y, fs);
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
                [file,path] = uigetfile({'*.mp3'}, 'Select an Audio File');
                app.AudioFile = audioread(fullfile(path, file));
                app.Fs = 44100; % Or you can use audiinfo to get the sample rate of the audio file
                
                app.NameSound.Text = file;
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            disp(value)
            if (strcmp(value, 'Lowpass')==1)
              

            end


        end

        % Button pushed function: PlaywithFilterButton
        function PlaywithFilterButtonPushed(app, event)
             if isempty(app.AudioFile)
                msgbox('Please select an audio file.');
                return;
             end
              if app.CutoffFrequency1 > app.CutoffFrequency2
                  msgbox('Frequency Error')
                  return
              end
                % Lowpass
              if (strcmp(app.FilterDropDown.Value, 'Lowpass')==1)
                fc1 = app.CutoffFrequency1.Value; % Cutoff frequency 1 (Hz)
                fc2 = app.CutoffFrequency2.Value; % Cutoff frequency 2 (Hz)
                N = 101; % Filter length

                % Design the filter
                
                b = fir1(N-1, [fc1/(app.Fs/2), fc2/(app.Fs/2)], 'bandpass');

                % Apply the filter to the audio signal
                y_filtered = filter(b, 1, app.AudioFile);
                sound(y_filtered);
                
              end
                % High Pass
              if (strcmp(app.FilterDropDown.Value, 'Highpass')==1)
                fc1 = app.CutoffFrequency1.Value; % Cutoff frequency 1 (Hz)
                fc2 = app.CutoffFrequency2.Value; % Cutoff frequency 2 (Hz)
                N = 101; % Filter length

                % Design the filter
                b = fir1(N-1, [fc1/(app.Fs/2), fc2/(app.Fs/2)], 'high');

                % Apply the filter to the audio signal
                y_filtered = filter(b, 1, app.AudioFile);
                sound(y_filtered);
                
              end
                % Bandpass
               if (strcmp(app.FilterDropDown.Value, 'Bandpass')==1)
                
                fc1 = app.CutoffFrequency1.Value; % Cutoff frequency 1 (Hz)
                fc2 = app.CutoffFrequency2.Value; % Cutoff frequency 2 (Hz)
                
                filteredAudio = bandpass(app.AudioFile, [fc1 fc2], app.Fs);
                % Apply the filter to the audio signal
               
                sound(filteredAudio);
                
               end
                % Notch
               if (strcmp(app.FilterDropDown.Value, 'Notch')==1)
                
                fc1 = app.CutoffFrequency1.Value; % Cutoff frequency 1 (Hz)
                fc2 = app.CutoffFrequency2.Value; % Cutoff frequency 2 (Hz)
                
                filteredAudio = bandstop(app.AudioFile, [fc1 fc2], app.Fs);
                % Apply the filter to the audio signal
               
                sound(filteredAudio);
                
               end
                % FIR
                if (strcmp(app.FilterDropDown.Value, 'FIR')==1)
                
                fc1 = app.CutoffFrequency1.Value; % Cutoff frequency 1 (Hz)
                fc2 = app.CutoffFrequency2.Value; % Cutoff frequency 2 (Hz)
                
                 % Design the FIR filter using the specified cutoff frequencies
                order = 100; % Filter order
                cutoff = [fc1 fc2]/(app.Fs/2); % Normalize cutoff frequencies
                b = fir1(order, cutoff, 'bandpass');
            
                % Apply the FIR filter to the audio signal
                filteredAudio = filter(b, 1, app.AudioFile);

                sound(filteredAudio);
                
                end

                % IIR 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AudioFilterApplicationLabel = uilabel(app.UIFigure);
            app.AudioFilterApplicationLabel.Text = 'Audio Filter Application ';
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'Lowpass', 'Highpass', 'Bandpass', 'Notch', 'FIR ', 'IIR'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'Lowpass';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.NameSound = uilabel(app.UIFigure);
            app.NameSound.Text = 'Name';
            app.PlaySoundButton = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound ';
            app.PlaywithFilterButton = uibutton(app.UIFigure, 'push');
            app.PlaywithFilterButton.ButtonPushedFcn = createCallbackFcn(app, @PlaywithFilterButtonPushed, true);
            app.PlaywithFilterButton.Text = 'Play with Filter';
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.CF1Label = uilabel(app.UIFigure);
            app.CF1Label.Text = 'CF  1';
            app.CutoffFrequency1 = uieditfield(app.UIFigure, 'numeric');
            app.CF2Label = uilabel(app.UIFigure);
            app.CF2Label.Text = 'CF 2';
            app.CutoffFrequency2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end